classdef Vitruvian_Man_NL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        SpeelvideoButton                matlab.ui.control.Button
        SpierkrachtEditField            matlab.ui.control.NumericEditField
        SpierkrachtEditFieldLabel       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MassaEditField                  matlab.ui.control.NumericEditField
        MassaEditFieldLabel             matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        GroepEditField_2                matlab.ui.control.EditField
        StartsimulatieButton            matlab.ui.control.Button
        DeManvanVitruviusLabel          matlab.ui.control.Label
        MaaktekeningButton              matlab.ui.control.Button
        LengtevanvoetEditField          matlab.ui.control.NumericEditField
        LengtevanvoetEditFieldLabel     matlab.ui.control.Label
        cmLabel_9                       matlab.ui.control.Label
        cmLabel_6                       matlab.ui.control.Label
        cmLabel_5                       matlab.ui.control.Label
        cmLabel_4                       matlab.ui.control.Label
        cmLabel_3                       matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        AfstandtussenschoudersEditField  matlab.ui.control.NumericEditField
        AfstandtussenschoudersEditFieldLabel  matlab.ui.control.Label
        AfstandvangrondtotknieEditField  matlab.ui.control.NumericEditField
        AfstandvangrondtotknieEditFieldLabel  matlab.ui.control.Label
        AfstandvanknietotheupEditField  matlab.ui.control.NumericEditField
        AfstandvanknietotheupEditFieldLabel  matlab.ui.control.Label
        AfstandvanelleboogtotschouderEditField  matlab.ui.control.NumericEditField
        AfstandvanelleboogtotschouderEditFieldLabel  matlab.ui.control.Label
        AfstandvanvingertoptotelleboogEditField  matlab.ui.control.NumericEditField
        AfstandvanvingertoptotelleboogLabel  matlab.ui.control.Label
        HoogteEditField                 matlab.ui.control.NumericEditField
        HoogteEditFieldLabel            matlab.ui.control.Label
        GroepEditField                  matlab.ui.control.EditField
        GroepEditFieldLabel             matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Layout
        ink_colour = [139,69,19]/256; % RGB code for "ink" i.e. text and figures
        paper_colour = [235,222,173]/256; % RGB code for "paper" i.e. background

        % Default values
        default_height = 1.8; % [m]
        default_ratio_fingertip_elbow = 1/4;
        default_ratio_elbow_shoulder = 1/8;
        default_ratio_shoulder_width = 1/4;
        default_ratio_hip_knee = 1/4;
        default_ratio_knee_ground = 1/4;
        default_ratio_foot_length = 1/7;

        % Allocate user input values
        usr_height = 1.8;
        usr_fingertip_elbow = 1/4*1.8;
        usr_elbow_shoulder = 1/8*1.8;
        usr_shoulder_width = 1/4*1.8;
        usr_hip_knee = 1/4*1.8;
        usr_knee_ground = 1/4*1.8;
        usr_foot_length = 1/7*1.8;

        % scale factors
        scale_factors

    end
    
    methods (Access = private)

        % user inputs with default values
        function [] = updateUserInput(app)
            app.usr_height = app.default_height;
            app.usr_fingertip_elbow = app.default_ratio_fingertip_elbow*app.usr_height;
            app.usr_elbow_shoulder = app.default_ratio_elbow_shoulder*app.usr_height;
            app.usr_shoulder_width = app.default_ratio_shoulder_width*app.usr_height;
            app.usr_hip_knee = app.default_ratio_hip_knee*app.usr_height;
            app.usr_knee_ground = app.default_ratio_knee_ground*app.usr_height;
            app.usr_foot_length = app.default_ratio_foot_length*app.usr_height;
        end

        % read user inputs
        function [] = readUserInput(app)
            app.usr_height = app.HoogteEditField.Value;
            app.usr_fingertip_elbow = app.AfstandvanvingertoptotelleboogEditField.Value;
            app.usr_elbow_shoulder = app.AfstandvanelleboogtotschouderEditField.Value;
            app.usr_shoulder_width = app.AfstandtussenschoudersEditField.Value;
            app.usr_hip_knee = app.AfstandvanknietotheupEditField.Value;
            app.usr_knee_ground = app.AfstandvangrondtotknieEditField.Value;
            app.usr_foot_length = app.LengtevanvoetEditField.Value;
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MaaktekeningButton
        function MaaktekeningButtonPushed(app, event)
            % read user inputs
            readUserInput(app)
            % call function to update drawing
            app.scale_factors = updateDrawing(app.usr_height,app.usr_fingertip_elbow,app.usr_elbow_shoulder,...
                app.usr_shoulder_width,app.usr_hip_knee,app.usr_knee_ground,app.usr_foot_length,...
                app.UIAxes,app.ink_colour,app.paper_colour);

        end

        % Button pushed function: StartsimulatieButton
        function StartsimulatieButtonPushed(app, event)
            % update drawing and get scale factors
            MaaktekeningButtonPushed(app, event)

            U.ModelName = app.GroepEditField_2.Value;
            U.GroupName = app.GroepEditField.Value;
            U.Height = app.usr_height;
            U.Mass = app.MassaEditField.Value;
            U.Force_sf = app.SpierkrachtEditField.Value/100;


            % start simulation
%             PredSim_wrapper_for_app(U,app.scale_factors);


            % add result to table
            if isempty(app.UITable.Data)
                app.UITable.Data = {'test','test_2'};
            else
                app.UITable.Data(end+1,1) = {'test'};
                app.UITable.Data(end,2) = {'test_2'};
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.GroepEditFieldLabel = uilabel(app.UIFigure);
            app.GroepEditFieldLabel.Text = 'Groep';
            app.GroepEditField = uieditfield(app.UIFigure, 'text');
            app.HoogteEditFieldLabel = uilabel(app.UIFigure);
            app.HoogteEditFieldLabel.Text = 'Hoogte';
            app.HoogteEditField = uieditfield(app.UIFigure, 'numeric');
            app.HoogteEditField.ValueDisplayFormat = '%111g';
            app.HoogteEditField.Value = 180;
            app.AfstandvanvingertoptotelleboogLabel = uilabel(app.UIFigure);
            app.AfstandvanvingertoptotelleboogLabel.Text = 'Afstand van vingertop tot elleboog';
            app.AfstandvanvingertoptotelleboogEditField = uieditfield(app.UIFigure, 'numeric');
            app.AfstandvanvingertoptotelleboogEditField.ValueDisplayFormat = '%111g';
            app.AfstandvanvingertoptotelleboogEditField.Value = 45;
            app.AfstandvanelleboogtotschouderEditFieldLabel = uilabel(app.UIFigure);
            app.AfstandvanelleboogtotschouderEditFieldLabel.Text = 'Afstand van elleboog tot schouder';
            app.AfstandvanelleboogtotschouderEditField = uieditfield(app.UIFigure, 'numeric');
            app.AfstandvanelleboogtotschouderEditField.ValueDisplayFormat = '%111g';
            app.AfstandvanelleboogtotschouderEditField.Value = 22.5;
            app.AfstandvanknietotheupEditFieldLabel = uilabel(app.UIFigure);
            app.AfstandvanknietotheupEditFieldLabel.Text = 'Afstand van knie tot heup';
            app.AfstandvanknietotheupEditField = uieditfield(app.UIFigure, 'numeric');
            app.AfstandvanknietotheupEditField.ValueDisplayFormat = '%111g';
            app.AfstandvanknietotheupEditField.Value = 45;
            app.AfstandvangrondtotknieEditFieldLabel = uilabel(app.UIFigure);
            app.AfstandvangrondtotknieEditFieldLabel.Text = 'Afstand van grond tot knie';
            app.AfstandvangrondtotknieEditField = uieditfield(app.UIFigure, 'numeric');
            app.AfstandvangrondtotknieEditField.ValueDisplayFormat = '%111g';
            app.AfstandvangrondtotknieEditField.Value = 45;
            app.AfstandtussenschoudersEditFieldLabel = uilabel(app.UIFigure);
            app.AfstandtussenschoudersEditFieldLabel.Text = 'Afstand tussen schouders';
            app.AfstandtussenschoudersEditField = uieditfield(app.UIFigure, 'numeric');
            app.AfstandtussenschoudersEditField.ValueDisplayFormat = '%111g';
            app.AfstandtussenschoudersEditField.Value = 45;
            app.cmLabel = uilabel(app.UIFigure);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.UIFigure);
            app.cmLabel_2.Text = 'cm';
            app.cmLabel_3 = uilabel(app.UIFigure);
            app.cmLabel_3.Text = 'cm';
            app.cmLabel_4 = uilabel(app.UIFigure);
            app.cmLabel_4.Text = 'cm';
            app.cmLabel_5 = uilabel(app.UIFigure);
            app.cmLabel_5.Text = 'cm';
            app.cmLabel_6 = uilabel(app.UIFigure);
            app.cmLabel_6.Text = 'cm';
            app.cmLabel_9 = uilabel(app.UIFigure);
            app.cmLabel_9.Text = 'cm';
            app.LengtevanvoetEditFieldLabel = uilabel(app.UIFigure);
            app.LengtevanvoetEditFieldLabel.Text = 'Lengte van voet';
            app.LengtevanvoetEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengtevanvoetEditField.ValueDisplayFormat = '%111g';
            app.LengtevanvoetEditField.Value = 25.7;
            app.MaaktekeningButton = uibutton(app.UIFigure, 'push');
            app.MaaktekeningButton.ButtonPushedFcn = createCallbackFcn(app, @MaaktekeningButtonPushed, true);
            app.MaaktekeningButton.Text = 'Maak tekening';
            app.DeManvanVitruviusLabel = uilabel(app.UIFigure);
            app.DeManvanVitruviusLabel.Text = 'De Man van Vitruvius';
            app.StartsimulatieButton = uibutton(app.UIFigure, 'push');
            app.StartsimulatieButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulatieButtonPushed, true);
            app.StartsimulatieButton.Text = 'Start simulatie';
            app.GroepEditField_2 = uieditfield(app.UIFigure, 'text');
            app.GroepEditField_2.Value = '(Kies een naam)';
            app.kgLabel = uilabel(app.UIFigure);
            app.kgLabel.Text = 'kg';
            app.MassaEditFieldLabel = uilabel(app.UIFigure);
            app.MassaEditFieldLabel.Text = 'Massa';
            app.MassaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassaEditField.ValueDisplayFormat = '%111g';
            app.MassaEditField.Value = 75;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.SpierkrachtEditFieldLabel = uilabel(app.UIFigure);
            app.SpierkrachtEditFieldLabel.Text = 'Spierkracht';
            app.SpierkrachtEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpierkrachtEditField.ValueDisplayFormat = '%111g';
            app.SpierkrachtEditField.Value = 100;
            app.SpeelvideoButton = uibutton(app.UIFigure, 'push');
            app.SpeelvideoButton.Text = 'Speel video';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Naam'; 'Afstand'};
            app.UITable.RowName = {};
            app.UITable.Multiselect = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vitruvian_Man_NL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end