classdef OpStapMetSuperKracht2025 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LengteMenu                      matlab.ui.container.Menu
        KrachtMenu                      matlab.ui.container.Menu
        ZwaartekrachtMenu               matlab.ui.container.Menu
        CreerjezelfalsmodelMenu         matlab.ui.container.Menu
        CheckBox_Reus                   matlab.ui.control.CheckBox
        giant                           matlab.ui.control.Image
        smurf                           matlab.ui.control.Image
        Volwassenen                     matlab.ui.control.Image
        Kleuters                        matlab.ui.control.Image
        Jullie_lengte                   matlab.ui.control.Image
        CheckBox_Volwassenen            matlab.ui.control.CheckBox
        CheckBox_Jullie_lengte          matlab.ui.control.CheckBox
        CheckBox_Kleuter                matlab.ui.control.CheckBox
        CheckBox_Smurf                  matlab.ui.control.CheckBox
        CheckBoxJupiter                 matlab.ui.control.CheckBox
        GIF                             matlab.ui.control.Image
        Ballon                          matlab.ui.control.Image
        CheckBox_Maui                   matlab.ui.control.CheckBox
        CheckBox_MrImpossible           matlab.ui.control.CheckBox
        CheckBox_Jullie_strength        matlab.ui.control.CheckBox
        CheckBox_Spongebob              matlab.ui.control.CheckBox
        CheckBox_Olaf                   matlab.ui.control.CheckBox
        MrIncredible                    matlab.ui.control.Image
        Jullie_strength                 matlab.ui.control.Image
        Spongebob                       matlab.ui.control.Image
        Olaf                            matlab.ui.control.Image
        Button                          matlab.ui.control.Button
        GroepEditField                  matlab.ui.control.EditField
        SpeelvideoButton                matlab.ui.control.Button
        SluitvideoButton                matlab.ui.control.Button
        StartsimulatieButton            matlab.ui.control.Button
        OpstapmetsuperkrachtWhite       matlab.ui.control.Label
        OpstapmetsuperkrachtBlue        matlab.ui.control.Label
        CheckBoxEarth                   matlab.ui.control.CheckBox
        CheckBoxMars                    matlab.ui.control.CheckBox
        CheckBoxMaan                    matlab.ui.control.CheckBox
        earth                           matlab.ui.control.Image
        Mars                            matlab.ui.control.Image
        Jupiter                         matlab.ui.control.Image
        Spaceman                        matlab.ui.control.Image
        PlanetXblack                    matlab.ui.control.Image
        maan                            matlab.ui.control.Image
        CheckBoxPluto                   matlab.ui.control.CheckBox
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        ZwaartekrachtLabelEigenModel    matlab.ui.control.Label
        SpierkrachtLabelEigenModel      matlab.ui.control.Label
        cmLabelEigenModel               matlab.ui.control.Label
        LengteLabelEigenModel           matlab.ui.control.Label
        gLabelEigenModel                matlab.ui.control.Label
        ZwaartekrachtEigenModel         matlab.ui.control.NumericEditField
        MassaEditFieldLabel             matlab.ui.control.Label
        kgLabelEigenModel               matlab.ui.control.Label
        SpierkrachtEigenModel           matlab.ui.control.NumericEditField
        procentlabelEigenModel          matlab.ui.control.Label
        GIFsmurf                        matlab.ui.control.Image
        DENKsmurf                       matlab.ui.control.Image
        GIFreus                         matlab.ui.control.Image
        DENKreus                        matlab.ui.control.Image
        PlaneetX                        matlab.ui.control.Image
        GroepEditFieldLabel             matlab.ui.control.Label
        spaceBackground                 matlab.ui.control.Image
        Maui                            matlab.ui.control.Image
        BackgroundImageSmurfReusKracht  matlab.ui.control.Image
        LengteSliderEigenModel          matlab.ui.control.RangeSlider
        NaamModelEditField              matlab.ui.control.EditField
        MassaEditFieldEigenModel        matlab.ui.control.NumericEditField
        Background_creerjeeigenmodel    matlab.ui.control.Image
    end

    
    properties (Access = public)
        % Layout
        ink_colour = [139,69,19]/256; % RGB code for "ink" i.e. text and figures
        paper_colour = [235,222,173]/256; % RGB code for "paper" i.e. background

        % Default values
        default_height = 1.8; % [m]
        default_ratio_fingertip_elbow = 1/4;
        default_ratio_elbow_shoulder = 1/8;
        default_ratio_shoulder_width = 1/4;
        default_ratio_hip_knee = 1/4;
        default_ratio_knee_ground = 1/4;
        default_ratio_foot_length = 1/6;

        % Allocate user input values
        usr_height = 1.8;
        usr_fingertip_elbow = 1/4*1.8;
        usr_elbow_shoulder = 1/8*1.8;
        usr_shoulder_width = 1/4*1.8;
        usr_hip_knee = 1/4*1.8;
        usr_knee_ground = 1/4*1.8;
        usr_foot_length = 1/6*1.8;

        usr_speed = 1.38;

        % scale factors
        scale_factors

        % storage
        path_repo
        path_results
        path_savefolder
        path_geom
        path_casadi
        ModelName
        GroupName
        tbldata = cell(1,4);
        idx_sel_data = 1;
        sel_mot_file
        sel_osim_file

    end
    properties (Access = private)
    CurrentMenu string = "Lengte";   % startwaarde
    end
    
    methods (Access = private)

        % Menu selected function: Lengte
        function LengteMenuSelected(app,event)
            app.CurrentMenu = "Lengte";  
            app.BackgroundImageSmurfReusKracht.Visible = 'on';
            app.OpstapmetsuperkrachtBlue.Visible = 'on';
            app.OpstapmetsuperkrachtWhite.Visible = 'off';

            % related to smurf reus
            app.giant.Visible = 'on';
            app.CheckBox_Reus.Visible = 'on';
            app.smurf.Visible = 'on';
            app.CheckBox_Smurf.Visible = 'on';
            app.Kleuters.Visible = 'on';
            app.CheckBox_Kleuter.Visible = 'on';
            app.Jullie_lengte.Visible = 'on';
            app.CheckBox_Jullie_lengte.Visible = 'on';
            app.Volwassenen.Visible = 'on';
            app.CheckBox_Volwassenen.Visible = 'on';
            %app.LengteSliderReusSmurf.Visible = 'on';

            % related to kracht 
            app.Spongebob.Visible = 'off';
            app.CheckBox_Spongebob.Visible = 'off';
            app.Maui.Visible = 'off';
            app.CheckBox_Maui.Visible = 'off';
            app.MrIncredible.Visible = 'off';
            app.CheckBox_MrImpossible.Visible = 'off';
            app.Jullie_strength.Visible  = 'off';
            app.CheckBox_Jullie_strength.Visible = 'off';
            app.Olaf.Visible  = 'off';
            app.CheckBox_Olaf.Visible = 'off';

            % space
            app.Spaceman.Visible = 'off';
            app.spaceBackground.Visible = 'off';
            app.CheckBoxMaan.Visible = 'off';
            app.earth.Visible ='off';
            app.CheckBoxEarth.Visible = 'off';
            app.Mars.Visible ='off';
            app.CheckBoxMars.Visible = 'off';
            app.Jupiter.Visible ='off';
            app.CheckBoxJupiter.Visible = 'off';
            app.PlaneetX.Visible = 'off';
            app.CheckBoxPluto.Visible = 'off'; 
            
            % Eigen model
            app.cmLabelEigenModel.Visible = 'off';
            app.LengteLabelEigenModel.Visible = 'off';
            app.LengteSliderEigenModel.Visible = 'off';
            app.gLabelEigenModel.Visible = 'off';
            app.PlanetXblack.Visible = 'off';
            app.NaamModelEditField.Visible = 'off';
            app.MassaEditFieldEigenModel.Visible = 'off';
            app.MassaEditFieldLabel.Visible = 'off';
            app.kgLabelEigenModel.Visible = 'off';
            app.procentlabelEigenModel.Visible = 'off';
            app.SpierkrachtEigenModel.Visible = 'off';

            app.Background_creerjeeigenmodel.Visible = 'off';
            app.ZwaartekrachtEigenModel.Visible = 'off';
            app.ZwaartekrachtLabelEigenModel.Visible = 'off';
            app.SpierkrachtLabelEigenModel.Visible = 'off';            
        end


        % Menu selected function: Kracht
        function KrachtMenuSelected(app,event)
            app.CurrentMenu = "Kracht";  
            app.BackgroundImageSmurfReusKracht.Visible = 'on';
            app.OpstapmetsuperkrachtBlue.Visible = 'on';
            app.OpstapmetsuperkrachtWhite.Visible = 'off';

            % related to kracht 
            app.Spongebob.Visible = 'on';
            app.CheckBox_Spongebob.Visible  = 'on';
            app.Maui.Visible = 'on';
            app.CheckBox_Maui.Visible = 'on';
            app.MrIncredible.Visible = 'on';
            app.CheckBox_MrImpossible.Visible = 'on';
            app.Jullie_strength.Visible  = 'on';
            app.CheckBox_Jullie_strength.Visible = 'on';
            app.Olaf.Visible  = 'on';
            app.CheckBox_Olaf.Visible = 'on';

            % related to smurf reus
            app.giant.Visible = 'off';
            app.CheckBox_Reus.Visible = 'off';
            app.smurf.Visible = 'off';
            app.CheckBox_Smurf.Visible = 'off';
            app.Kleuters.Visible = 'off';
            app.CheckBox_Kleuter.Visible = 'off';
            app.Jullie_lengte.Visible = 'off';
            app.CheckBox_Jullie_lengte.Visible = 'off';
            app.Volwassenen.Visible = 'off';
            app.CheckBox_Volwassenen.Visible = 'off';
            %app.LengteSliderReusSmurf.Visible = 'on';


            % space
            app.Spaceman.Visible = 'off';
            app.spaceBackground.Visible = 'off';
            app.maan.Visible= 'off'; 
            app.CheckBoxMaan.Visible = 'off';
            app.earth.Visible ='off';
            app.CheckBoxEarth.Visible = 'off';
            app.Mars.Visible ='off';
            app.CheckBoxMars.Visible = 'off';
            app.Jupiter.Visible ='off';
            app.CheckBoxJupiter.Visible = 'off';
            app.PlaneetX.Visible = 'off';
            app.CheckBoxPluto.Visible = 'off'; 
            
            % Eigen model
            app.cmLabelEigenModel.Visible = 'off';
            app.LengteLabelEigenModel.Visible = 'off';
            app.LengteSliderEigenModel.Visible = 'off';
            app.gLabelEigenModel.Visible = 'off';
            app.PlanetXblack.Visible = 'off';
            app.NaamModelEditField.Visible = 'off';
            app.MassaEditFieldEigenModel.Visible = 'off';
            app.MassaEditFieldLabel.Visible = 'off';
            app.kgLabelEigenModel.Visible = 'off';
            app.procentlabelEigenModel.Visible = 'off';
            app.SpierkrachtEigenModel.Visible = 'off';

            app.Background_creerjeeigenmodel.Visible = 'off';
            app.ZwaartekrachtEigenModel.Visible = 'off';
            app.ZwaartekrachtLabelEigenModel.Visible = 'off';
            app.SpierkrachtLabelEigenModel.Visible = 'off'; 

        end


        % Menu selected function: ZwaarteKracht
        function ZwaartekrachtMenuSelectedFix(app,event)
                app.CurrentMenu = "Zwaartekracht";  


            % related to smurf reus kracht
            app.BackgroundImageSmurfReusKracht.Visible = 'off';
            app.OpstapmetsuperkrachtBlue.Visible = 'off';

            % related to kracht 
            app.Spongebob.Visible = 'off';
            app.CheckBox_Spongebob.Visible = 'off';
            app.Maui.Visible = 'off';
            app.CheckBox_Maui.Visible = 'off';
            app.MrIncredible.Visible = 'off';
            app.CheckBox_MrImpossible.Visible = 'off';
            app.Jullie_strength.Visible  = 'off';
            app.CheckBox_Jullie_strength.Visible = 'off'; 
           app.Olaf.Visible  = 'off';
            app.CheckBox_Olaf.Visible = 'off';

            % related to smurf reus
             app.giant.Visible = 'off';
            app.CheckBox_Reus.Visible = 'off';
            app.smurf.Visible = 'off';
            app.CheckBox_Smurf.Visible = 'off';
            app.Kleuters.Visible = 'off';
            app.CheckBox_Kleuter.Visible = 'off';
            app.Jullie_lengte.Visible = 'off';
            app.CheckBox_Jullie_lengte.Visible = 'off';
            app.Volwassenen.Visible = 'off';
            app.CheckBox_Volwassenen.Visible = 'off';
            %app.LengteSliderReusSmurf.Visible = 'on';

            % Space
            app.Spaceman.Visible = 'on';
            app.spaceBackground.Visible = 'on';
            app.maan.Visible= 'on'; 
            app.CheckBoxMaan.Visible = 'on';
            app.earth.Visible ='on';
            app.CheckBoxEarth.Visible = 'on';
            app.Mars.Visible ='on';
            app.CheckBoxMars.Visible = 'on';
            app.Jupiter.Visible ='on';
            app.CheckBoxJupiter.Visible = 'on';
            app.PlaneetX.Visible = 'on';
            app.CheckBoxPluto.Visible = 'on'; 

            % Eigen model
            app.cmLabelEigenModel.Visible = 'off';
            app.LengteLabelEigenModel.Visible = 'off';
            app.LengteSliderEigenModel.Visible = 'off';
            app.gLabelEigenModel.Visible = 'off';
            app.PlanetXblack.Visible = 'off';
            app.NaamModelEditField.Visible = 'off';
            app.MassaEditFieldEigenModel.Visible = 'off';
            app.MassaEditFieldLabel.Visible = 'off';
            app.kgLabelEigenModel.Visible = 'off';
            app.procentlabelEigenModel.Visible = 'off';
            app.SpierkrachtEigenModel.Visible = 'off';


            app.Background_creerjeeigenmodel.Visible = 'off';
            app.ZwaartekrachtEigenModel.Visible = 'off';
            app.ZwaartekrachtLabelEigenModel.Visible = 'off';
            app.SpierkrachtLabelEigenModel.Visible = 'off';

        end


        function CreerjezelfalsmodelMenuSelected(app,event)
            app.CurrentMenu = "EigenModel";  
            % related to smurf reus kracht
            app.BackgroundImageSmurfReusKracht.Visible = 'off';
            app.OpstapmetsuperkrachtBlue.Visible = 'off';

            % related to kracht 
            app.Spongebob.Visible = 'off';
            app.CheckBox_Spongebob.Visible = 'off';
            app.Maui.Visible = 'off';
            app.CheckBox_Maui.Visible = 'off';
            app.MrIncredible.Visible = 'off';
            app.CheckBox_MrImpossible.Visible = 'off';
            app.Jullie_strength.Visible  = 'off';
            app.CheckBox_Jullie_strength.Visible = 'off';
            app.Olaf.Visible  = 'off';
            app.CheckBox_Olaf.Visible = 'off';

            % related to smurf reus
             app.giant.Visible = 'off';
            app.CheckBox_Reus.Visible = 'off';
            app.smurf.Visible = 'off';
            app.CheckBox_Smurf.Visible = 'off';
            app.Kleuters.Visible = 'off';
            app.CheckBox_Kleuter.Visible = 'off';
            app.Jullie_lengte.Visible = 'off';
            app.CheckBox_Jullie_lengte.Visible = 'off';
            app.Volwassenen.Visible = 'off';
            app.CheckBox_Volwassenen.Visible = 'off';
            %app.LengteSliderReusSmurf.Visible = 'on';

            % space eigen model
            app.OpstapmetsuperkrachtWhite.Visible = 'on';
            
            % Space
            app.Spaceman.Visible = 'off';
            app.spaceBackground.Visible = 'off';
            app.maan.Visible= 'off'; 
            app.CheckBoxMaan.Visible = 'off';
            app.earth.Visible ='off';
            app.CheckBoxEarth.Visible = 'off';
            app.Mars.Visible ='off';
            app.CheckBoxMars.Visible = 'off';
            app.Jupiter.Visible ='off';
            app.CheckBoxJupiter.Visible = 'off';
            app.PlaneetX.Visible = 'off';
            app.CheckBoxPluto.Visible = 'off'; 

            % Eigen model    
            app.cmLabelEigenModel.Visible = 'on';
            app.LengteLabelEigenModel.Visible = 'on';
            app.LengteSliderEigenModel.Visible = 'on';
            app.gLabelEigenModel.Visible = 'on';
            app.PlanetXblack.Visible = 'on';
            app.NaamModelEditField.Visible = 'on';
            app.MassaEditFieldEigenModel.Visible = 'on';
            app.MassaEditFieldLabel.Visible = 'on';
            app.kgLabelEigenModel.Visible = 'on';
            app.procentlabelEigenModel.Visible = 'on';
            app.SpierkrachtEigenModel.Visible = 'on';
            app.Background_creerjeeigenmodel.Visible = 'on';
            app.ZwaartekrachtEigenModel.Visible = 'on';
            app.ZwaartekrachtLabelEigenModel.Visible = 'on';
            app.SpierkrachtLabelEigenModel.Visible = 'on';

        end

% 
%         % user inputs with default values
%         function [] = updateUserInput(app)
% %             app.usr_height = app.default_height;
%             app.usr_fingertip_elbow = app.default_ratio_fingertip_elbow*app.usr_height;
%             app.usr_elbow_shoulder = app.default_ratio_elbow_shoulder*app.usr_height;
%             app.usr_shoulder_width = app.default_ratio_shoulder_width*app.usr_height;
%             app.usr_hip_knee = app.default_ratio_hip_knee*app.usr_height;
%             app.usr_knee_ground = app.default_ratio_knee_ground*app.usr_height;
%             app.usr_foot_length = app.default_ratio_foot_length*app.usr_height;
%         end
% 
%         % read user inputs
%         function [] = readUserInput(app)
%             % app.usr_height = app.LichaamslengteEditField.Value;
%             app.usr_fingertip_elbow = app.AfstandelleboogtotvingertopEditField.Value;
%             app.usr_elbow_shoulder = app.AfstandschoudertotelleboogEditField.Value;
%             app.usr_shoulder_width = app.AfstandtussenschoudersEditField.Value;
%             app.usr_hip_knee = app.AfstandheuptotknieEditField.Value;
%             app.usr_knee_ground = app.AfstandknietotgrondEditField.Value;
%             app.usr_foot_length = app.LengtevanvoetEditField.Value;
%         end
% 

        function [total_distance, avg_vel] = calcDistance(app,savepath)
            %% get distance
            E_metab = 778e3; % 778 kJ = 1 pancake
            load(savepath,'R');
            COT = R.metabolics.Bhargava2004.COT;
            total_distance = round(E_metab/(COT*R.misc.body_mass));
            avg_vel = round(R.kinematics.avg_velocity*3.6);
            clear('R');

        end

        function name_tmp2 = getName(app,name_tmp)
            if strcmp(name_tmp(end-2:end-1),'_v')
                name_tmp2 = name_tmp(1:end-3);
            elseif strcmp(name_tmp(end-3:end-2),'_v')
                name_tmp2 = name_tmp(1:end-4);
            else
                name_tmp2 = name_tmp;
            end
        end

        
%         function setPaths(app)
%             name = getenv('COMPUTERNAME');
% % 
% %             % fill these in based on your computer
%             if strcmp(name, 'GBW-L-W4394')
%                 init_path_savefolder = 'C:\Users\u0125183\OneDrive - KU Leuven\Kinderuniversiteit';
%                 init_path_geom = 'C:\GBW_MyPrograms\OpenSim 4.5\Geometry';
%                 init_path_casadi = 'C:\GBW_MyPrograms\Casadi';
%             elseif strcmp(name,'GBW-L-W2109')
%                 init_path_savefolder = 'C:\GBW_MyPrograms\PredSim\Subjects';
%                 init_path_geom = 'C:\GBW_MyPrograms\OpenSim\OpenSim 4.3\Geometry';
%                 init_path_casadi = 'C:\GBW_MyPrograms\Casadi\casadi-windows-matlabR2016a-v3.5.5';
%             %elseif strcmp(name,'GBW-L-W2195')
%             %     init_path_savefolder = 'C:\GBW_MyPrograms\KinderuniversiteitApp\Resultaten';
%             %     init_path_geom = 'C:\OpenSim 4.4\Geometry';
%             %     init_path_casadi = 'C:\GBW_MyPrograms\casadi_3_5_5';
% %             elseif strcmp(name, 'GBW-L-W2075')
% %                 init_path_savefolder = 'C:\Users\u0138016\OneDrive - KU Leuven\Outreach\Kinderuniversiteit\2022\Resultaten';
% %                 init_path_geom = 'C:\GBW_MyPrograms\OpenSim 4.3\Geometry';
% %                 init_path_casadi = 'C:\GBW_MyPrograms\casadi_3_5_5';
% %             else
% %                 init_path_savefolder = 'C:\Users\u0138016\OneDrive - KU Leuven\Outreach\Kinderuniversiteit\2022\Resultaten';
% %                 init_path_geom = 'C:\GBW_MyPrograms\OpenSim 4.3\Geometry';
% %                 init_path_casadi = 'C:\GBW_MyPrograms\casadi_3_5_5';
%             end
% 
% 
% 
% 
%         end
        function initPaths(app)
        [init_path_savefolder, init_path_geom, init_path_casadi] = getPaths();
            %test given paths
            if ~exist(init_path_savefolder,'dir')
                error('Please set "init_path_savefolder"')
            end
            if ~exist(init_path_geom,'dir')
                error('Please set "init_path_geom"')
            end
            if ~exist(init_path_casadi,'dir')
                error('Please set "init_path_casadi"')
            end

            % set properties
            app.path_savefolder = init_path_savefolder;
            app.path_geom = init_path_geom;
            app.path_casadi = init_path_casadi;
        end
        
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % make default drawing

            % path repo
            [pathApp,~,~] = fileparts(mfilename('fullpath'));
            [pathRepo,~,~] = fileparts(pathApp);
            app.path_repo = pathRepo;

            addpath([pathRepo '\VariousFunctions'])
            addpath([pathRepo '\App2025'])

            %app.sel_mot_file = fullfile(app.path_repo,'Subjects','Vitruvian_Man','Vitruvian_Man.mot');

            %app.sel_osim_file = fullfile(app.path_repo,'Subjects','Vitruvian_Man','Vitruvian_Man.osim');

            %setPaths(app)
            initPaths(app)

            % menu
           app.LengteMenu.MenuSelectedFcn = @(src, event) LengteMenuSelected(app,event);
           app.KrachtMenu.MenuSelectedFcn = @(src, event) KrachtMenuSelected(app,event);
           app.ZwaartekrachtMenu.MenuSelectedFcn = @(src,event) ZwaartekrachtMenuSelectedFix(app,event); 
           app.CreerjezelfalsmodelMenu.MenuSelectedFcn =  @(src,event) CreerjezelfalsmodelMenuSelected(app,event); 
            
           app.CurrentMenu = "Lengte";  
          
            app.Spongebob.Visible = 'off';
            app.CheckBox_Spongebob.Visible = 'off';
            app.Maui.Visible = 'off';
            app.CheckBox_Maui.Visible = 'off';
            app.MrIncredible.Visible = 'off';
            app.CheckBox_MrImpossible.Visible = 'off';
            app.Jullie_strength.Visible  = 'off';
            app.CheckBox_Jullie_strength.Visible = 'off';
            app.Olaf.Visible  = 'off';
            app.CheckBox_Olaf.Visible = 'off';
              
            
            app.OpstapmetsuperkrachtWhite.Visible = 'off';
            app.Spaceman.Visible = 'off';
            app.spaceBackground.Visible = 'off';
            app.maan.Visible= 'off'; 
            app.CheckBoxMaan.Visible = 'off';
            app.earth.Visible ='off';
            app.CheckBoxEarth.Visible = 'off';
            app.Mars.Visible ='off';
            app.CheckBoxMars.Visible = 'off';
            app.Jupiter.Visible ='off';
            app.CheckBoxJupiter.Visible = 'off';
            app.PlaneetX.Visible = 'off';
            app.CheckBoxPluto.Visible = 'off'; 


            app.cmLabelEigenModel.Visible = 'off';
            app.LengteLabelEigenModel.Visible = 'off';
            app.LengteSliderEigenModel.Visible = 'off';
            app.gLabelEigenModel.Visible = 'off';
            app.PlanetXblack.Visible = 'off';
            app.NaamModelEditField.Visible = 'off';
            app.MassaEditFieldEigenModel.Visible = 'off';
            app.MassaEditFieldLabel.Visible = 'off';
            app.kgLabelEigenModel.Visible = 'off';
            app.procentlabelEigenModel.Visible = 'off';
            app.SpierkrachtEigenModel.Visible = 'off';
            app.Background_creerjeeigenmodel.Visible = 'off';
            app.ZwaartekrachtEigenModel.Visible = 'off';
            app.ZwaartekrachtLabelEigenModel.Visible = 'off';
            app.SpierkrachtLabelEigenModel.Visible = 'off';
            app.OpstapmetsuperkrachtBlue.Visible = 'on';
            

        end

        % Callback function
        function MaaktekeningButtonPushed(app, event)

        end

        % Button pushed function: StartsimulatieButton
        function StartsimulatieButtonPushed(app, event)


            if isempty(app.GroepEditField)
                warning('Kies een groepsnaam.')
                return
            end

            if strcmp(app.CurrentMenu,'Kracht')
                allChecks = [app.CheckBox_Olaf.Value, app.CheckBox_Spongebob.Value, ...
                app.CheckBox_Jullie_strength.Value, app.CheckBox_MrImpossible.Value, ...
                 app.CheckBox_Maui.Value];
                modelNames = {'Olaf','SpongeBob','Jullie','MrImpossible','Maui'};
                sf_strength = [0.1 0.5 1 2 5];
                % i_checks = logical(allChecks);
                U.Force_sf = sum(allChecks.*sf_strength);
                i_checks = logical(allChecks);
                U.ModelName = modelNames{i_checks};
                xpos_ballon_all = [265 508 693 1009 1336];
                ypos_ballon_all = [413 413 441 492 432];
                xpos_ballon_current = xpos_ballon_all(i_checks);
                ypos_ballon_current = ypos_ballon_all(i_checks);

                xpos_gif_all = [313 556 741 1057 1384];
                ypos_gif_all = [470 470 498 549 489];
                xpos_gif_current = xpos_gif_all(i_checks);
                ypos_gif_current = ypos_gif_all(i_checks);

                app.Ballon.Position = [xpos_ballon_current ypos_ballon_current 214 177];
                app.GIF.Position = [xpos_gif_current ypos_gif_current 98 71];
            
            else
                U.Force_sf = 1;
            end
            
            if strcmp(app.CurrentMenu,'Zwaartekracht')
                allChecks = [app.CheckBoxMaan.Value, app.CheckBoxMars.Value, ...
                app.CheckBoxEarth.Value, app.CheckBoxJupiter.Value, app.CheckBoxPluto.Value];
                modelNames = {'Maan','Mars','Aarde','Jupiter','Pluto'};
                sf_gravity = [0.17 0.38 1 2.53 0.06]; % units = g
                % i_checks = logical(allChecks);
                U.sf_Gravity = sum(allChecks.*sf_gravity);
                i_checks = logical(allChecks);
                U.ModelName = modelNames{i_checks};
                xpos_ballon_all = [265 508 693 1009 1336];
                ypos_ballon_all = [413 413 441 492 432];
                xpos_ballon_current = xpos_ballon_all(i_checks);
                ypos_ballon_current = ypos_ballon_all(i_checks);

                xpos_gif_all = [313 556 741 1057 1384];
                ypos_gif_all = [470 470 498 549 489];
                xpos_gif_current = xpos_gif_all(i_checks);
                ypos_gif_current = ypos_gif_all(i_checks);

                app.Ballon.Position = [xpos_ballon_current ypos_ballon_current 214 177];
                app.GIF.Position = [xpos_gif_current ypos_gif_current 98 71];
            
            else
                U.sf_Gravity = 1;
            end

            if strcmp(app.CurrentMenu,'Lengte')
                allChecks = [app.CheckBox_Smurf.Value, app.CheckBox_Kleuter.Value, ...
                app.CheckBox_Jullie_lengte.Value, app.CheckBox_Volwassenen.Value, ...
                 app.CheckBox_Reus.Value];
                modelNames = {'Smurf','Kleuter','Jullie_lengte','Volwassenen','Reus'};
                v_Length = [20 80 120 180 400];
                v_Mass = [2 12 35 62 200];
                v_Speed = [0.20 0.53 0.85 1.20 2.7];
                %v_IG_pelvis_y = [0.115 0.460 0.690 1.02 2.3];
                %i_checks = logical(allChecks);
                U.Height = sum(allChecks.*v_Length);
                U.Mass = sum(allChecks.*v_Mass);
                U.Speed = sum(allChecks.*v_Speed);
                %U.IG_pelvis_y = sum(allChecks.*v_IG_pelvis_y);
                i_checks = logical(allChecks);
                U.ModelName = modelNames{i_checks};
                xpos_ballon_all = [265 508 693 1009 1336];
                ypos_ballon_all = [413 413 441 492 432];
                xpos_ballon_current = xpos_ballon_all(i_checks);
                ypos_ballon_current = ypos_ballon_all(i_checks);

                xpos_gif_all = [313 556 741 1057 1384];
                ypos_gif_all = [470 470 498 549 489];
                xpos_gif_current = xpos_gif_all(i_checks);
                ypos_gif_current = ypos_gif_all(i_checks);

                app.Ballon.Position = [xpos_ballon_current ypos_ballon_current 214 177];
                app.GIF.Position = [xpos_gif_current ypos_gif_current 98 71];
            
            else
                U.Height = 180;
                U.Speed = 1.33;
                U.Mass = 62;
                %U.IG_pelvis_y = [];
            end

              app.Ballon.Enable = 'on';
              app.Ballon.Visible = 'on';
              app.GIF.Enable = 'on';
              app.GIF.Visible = 'on';

            U.savefolder = app.path_savefolder;
            U.GroupName = app.GroupName;
            U.PathCasadi = app.path_casadi;
            %U.Height = 180;
            %U.Speed = 1.33;
            %U.Mass = 62;
            % U.sf_Gravity = 1;
            sf.foot = 1;
            sf.upp_leg = 1;
            sf.low_leg = 1;
            sf.torso = 1;
            sf.shoulder = 1;
            sf.low_arm = 1;
            sf.upp_arm = 1;
            app.ModelName = U.ModelName;

            % start simulation
            resultpath = PredSim_wrapper_for_app(U,sf);


              app.Ballon.Enable = 'off';
              app.Ballon.Visible = 'off';
              app.GIF.Enable = 'off';
              app.GIF.Visible = 'off';


        end

        % Value changed function: GroepEditField
        function GroepEditFieldValueChanged(app, event)
            if isempty(app.GroepEditField.Value)
                return
            end
            % avoid issues with paths
            GroupName_tmp = app.GroepEditField.Value;
            GroupName_tmp(strfind(GroupName_tmp,' ')) = '_';
            GroupName_tmp(strfind(GroupName_tmp,'/')) = '_';
            GroupName_tmp(strfind(GroupName_tmp,'\')) = '_';
            
            while strcmp(GroupName_tmp(1),'_') && length(GroupName_tmp)>1
                GroupName_tmp = GroupName_tmp(2:end);
            end
            
            while strcmp(GroupName_tmp(end),'_') && length(GroupName_tmp)>1
                GroupName_tmp = GroupName_tmp(1:end-1);
            end
            app.GroupName = GroupName_tmp;

            % load table with results for this group
            % loadResultsTable(app);
            
        end

        % Callback function
        function NaamEditFieldValueChanged(app, event)
           % if isempty(app.NaamModelEditField.Value)
           %     return
           % end
           % ModelName_tmp = app.NaamModelEditField.Value;
            % ModelName_tmp(strfind(ModelName_tmp,' ')) = '_';
            % ModelName_tmp(strfind(ModelName_tmp,'/')) = '_';
            % ModelName_tmp(strfind(ModelName_tmp,'\')) = '_';
            % 
            % while strcmp(ModelName_tmp(1),'_') && length(ModelName_tmp)>1
            %     ModelName_tmp = ModelName_tmp(2:end);
            % end
            % 
            % while strcmp(ModelName_tmp(end),'_') && length(ModelName_tmp)>1
            %     ModelName_tmp = ModelName_tmp(1:end-1);
            % end
            % app.ModelName = ModelName_tmp;

        end

        % Button pushed function: SpeelvideoButton
        function SpeelvideoButtonPushed(app, event)

           if isempty(app.GroepEditField)
                warning('Kies een groepsnaam.')
                return
            end

            if strcmp(app.CurrentMenu,'Kracht')
                allChecks = [app.CheckBox_Olaf.Value, app.CheckBox_Spongebob.Value, ...
                app.CheckBox_Jullie_strength.Value, app.CheckBox_MrImpossible.Value, ...
                 app.CheckBox_Maui.Value];
                modelNames = {'Olaf','SpongeBob','Jullie','MrImpossible','Maui'};
                sf_strength = [0.1 0.5 1 2 5];
                % i_checks = logical(allChecks);
                U.Force_sf = sum(allChecks.*sf_strength);
                i_checks = logical(allChecks);
                U.ModelName = modelNames{i_checks};
                xpos_ballon_all = [265 508 693 1009 1336];
                ypos_ballon_all = [413 413 441 492 432];
                xpos_ballon_current = xpos_ballon_all(i_checks);
                ypos_ballon_current = ypos_ballon_all(i_checks);

                xpos_gif_all = [313 556 741 1057 1384];
                ypos_gif_all = [470 470 498 549 489];
                xpos_gif_current = xpos_gif_all(i_checks);
                ypos_gif_current = ypos_gif_all(i_checks);

                app.Ballon.Position = [xpos_ballon_current ypos_ballon_current 214 177];
                app.GIF.Position = [xpos_gif_current ypos_gif_current 98 71];
            
            else
                U.Force_sf = 1;
            end
            
            if strcmp(app.CurrentMenu,'Zwaartekracht')
                allChecks = [app.CheckBoxMaan.Value, app.CheckBoxMars.Value, ...
                app.CheckBoxEarth.Value, app.CheckBoxJupiter.Value, app.CheckBoxPluto.Value];
                modelNames = {'Maan','Mars','Aarde','Jupiter','Pluto'};
                sf_gravity = [0.17 0.38 1 2.53 0.06]; % units = g
                % i_checks = logical(allChecks);
                U.sf_Gravity = sum(allChecks.*sf_gravity);
                i_checks = logical(allChecks);
                U.ModelName = modelNames{i_checks};
                xpos_ballon_all = [265 508 693 1009 1336];
                ypos_ballon_all = [413 413 441 492 432];
                xpos_ballon_current = xpos_ballon_all(i_checks);
                ypos_ballon_current = ypos_ballon_all(i_checks);

                xpos_gif_all = [313 556 741 1057 1384];
                ypos_gif_all = [470 470 498 549 489];
                xpos_gif_current = xpos_gif_all(i_checks);
                ypos_gif_current = ypos_gif_all(i_checks);

                app.Ballon.Position = [xpos_ballon_current ypos_ballon_current 214 177];
                app.GIF.Position = [xpos_gif_current ypos_gif_current 98 71];
            
            else
                U.sf_Gravity = 1;
            end

            if strcmp(app.CurrentMenu,'Lengte')
                allChecks = [app.CheckBox_Smurf.Value, app.CheckBox_Kleuter.Value, ...
                app.CheckBox_Jullie_lengte.Value, app.CheckBox_Volwassenen.Value, ...
                 app.CheckBox_Reus.Value];
                modelNames = {'Smurf','Kleuter','Jullie_lengte','Volwassenen','Reus'};
                v_Length = [20 80 120 180 400];
                v_Mass = [2 12 35 62 200];
                v_Speed = [0.20 0.53 0.85 1.20 2.7];
                %v_IG_pelvis_y = [0.115 0.460 0.690 1.02 2.3];
                %i_checks = logical(allChecks);
                U.Height = sum(allChecks.*v_Length);
                U.Mass = sum(allChecks.*v_Mass);
                U.Speed = sum(allChecks.*v_Speed);
                %U.IG_pelvis_y = sum(allChecks.*v_IG_pelvis_y);
                i_checks = logical(allChecks);
                U.ModelName = modelNames{i_checks};
                xpos_ballon_all = [265 508 693 1009 1336];
                ypos_ballon_all = [413 413 441 492 432];
                xpos_ballon_current = xpos_ballon_all(i_checks);
                ypos_ballon_current = ypos_ballon_all(i_checks);

                xpos_gif_all = [313 556 741 1057 1384];
                ypos_gif_all = [470 470 498 549 489];
                xpos_gif_current = xpos_gif_all(i_checks);
                ypos_gif_current = ypos_gif_all(i_checks);

                app.Ballon.Position = [xpos_ballon_current ypos_ballon_current 214 177];
                app.GIF.Position = [xpos_gif_current ypos_gif_current 98 71];
            
            else
                U.Height = 180;
                U.Speed = 1.33;
                U.Mass = 62;
                %U.IG_pelvis_y = [];
            end

              app.Ballon.Enable = 'on';
              app.Ballon.Visible = 'on';
              app.GIF.Enable = 'on';
              app.GIF.Visible = 'on';

            U.savefolder = app.path_savefolder;
            U.GroupName = app.GroupName;
            U.PathCasadi = app.path_casadi;
            %U.Height = 180;
            %U.Speed = 1.33;
            %U.Mass = 62;
            % U.sf_Gravity = 1;
            sf.foot = 1;
            sf.upp_leg = 1;
            sf.low_leg = 1;
            sf.torso = 1;
            sf.shoulder = 1;
            sf.low_arm = 1;
            sf.upp_arm = 1;
            app.ModelName = U.ModelName;


            % flag = 0;
            % if ~exist(app.sel_osim_file,'file')
            %     flag = 1;
            %     disp(['Could not find "' app.sel_osim_file '"'])
            % end
            % if ~exist(app.sel_mot_file,'file')
            %     flag = 1;
            %     disp(['Could not find "' app.sel_mot_file '"'])
            % end

            % if flag
            % app.sel_osim_file = fullfile(app.path_repo,'Subjects','Vitruvian_Man','Vitruvian_Man.osim');
            % app.sel_mot_file = fullfile(app.path_repo,'Subjects','Vitruvian_Man','Vitruvian_Man.mot');
            app.sel_osim_file = fullfile(app.path_repo,'Subjects',app.GroupName,app.ModelName,[app.ModelName '.osim']);

            mot_folder = fullfile(app.path_repo, 'Subjects',app.GroupName,app.ModelName);
            files = dir(fullfile(mot_folder, [app.ModelName '_v*.mot']));
            if isempty(files), error('No mot files found.'); end

            nums = cellfun(@(s) sscanf(s, [app.ModelName '_v%d.mot']), {files.name});
            [~, idx] = max(nums);

            app.sel_mot_file = fullfile(mot_folder, files(idx).name);

            % end

            % function
            try
                playVideo(app.sel_osim_file,app.sel_mot_file,app.path_geom);
            catch
            end

            % make close button active
            app.SpeelvideoButton.Enable = 'off';
            app.SpeelvideoButton.Visible = 'off';

            app.SluitvideoButton.Enable = 'on';
            app.SluitvideoButton.Visible = 'on';

        end

        % Button pushed function: SluitvideoButton
        function SluitvideoButtonPushed(app, event)
            try
                [~,~] = system('taskkill /IM simbody-visualizer.exe');
            catch
            end

            app.SluitvideoButton.Enable = 'off';
            app.SluitvideoButton.Visible = 'off';

            app.SpeelvideoButton.Enable = 'on';
            app.SpeelvideoButton.Visible = 'on';

        end

        % Callback function
        function NaamAfstandSnelheidListBoxValueChanged(app, event)
            app.sel_mot_file = app.NaamAfstandSnelheidListBox.Value;

            [~,name_tmp,~] = fileparts(app.sel_mot_file);
            name_tmp2 = getName(app,name_tmp);
            osim_tmp = fullfile(app.path_repo,'Subjects',name_tmp2,[name_tmp2 '.osim']);
            if exist(osim_tmp,'file')
                app.sel_osim_file = osim_tmp;
            else
                res_tmp = app.sel_mot_file;
                res_tmp(end-1) = 'a'; % .mot -> .mat
                load (res_tmp,'model_info');
                app.sel_osim_file = model_info.osim_path;
            end
            

        end

        % Callback function
        function KrachtSliderValueChanged(app, event)
            v = app.KrachtSlider.Value;
            if v < app.KrachtSlider.Limits(2)
                app.usr_speed = round(app.KrachtSlider.Value)/3.6;
            else
                app.usr_speed = -1;
            end
            
        end

        % Callback function
        function LichaamslengteEditFieldValueChanged(app, event)
            readUserInput(app)
            updateUserInput(app)
            writeDefaultUserInput(app)
            updateInputLimits(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LengteMenu = uimenu(app.UIFigure);
            app.LengteMenu.Text = '         Wat gebeurt er als je heel klein bent of juist heel groot?         ';
            app.KrachtMenu = uimenu(app.UIFigure);
            app.KrachtMenu.Text = '         Wat als je spieren extra sterk of juist heel zwak zijn?          ';
            app.ZwaartekrachtMenu = uimenu(app.UIFigure);
            app.ZwaartekrachtMenu.Text = '         Hoe beweeg je als de zwaartekracht anders is, zoals op de maan of op een andere planeet?         ';
            app.CreerjezelfalsmodelMenu = uimenu(app.UIFigure);
            app.CreerjezelfalsmodelMenu.Text = 'Creëer jezelf  als model!';
            app.Background_creerjeeigenmodel = uiimage(app.UIFigure);
            app.Background_creerjeeigenmodel.ImageSource = fullfile(pathToMLAPP, 'createyourownmodel_v2.png');
            app.MassaEditFieldEigenModel = uieditfield(app.UIFigure, 'numeric');
            app.MassaEditFieldEigenModel.Limits = [20 300];
            app.MassaEditFieldEigenModel.ValueDisplayFormat = '%111g';
            app.MassaEditFieldEigenModel.Value = 75;
            app.NaamModelEditField = uieditfield(app.UIFigure, 'text');
            app.NaamModelEditField.Placeholder = '(Geef de naam van jouw groepje)';
            app.NaamModelEditField.Value = '(Geef de naam van het model)';
            app.LengteSliderEigenModel = uislider(app.UIFigure, 'range');
            app.LengteSliderEigenModel.Limits = [80 180];
            app.LengteSliderEigenModel.MajorTicks = [80 100 120 140 160 180];
            app.LengteSliderEigenModel.MinorTicks = [80 85 90.95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175180];
            app.LengteSliderEigenModel.Value = [80 180];
            app.BackgroundImageSmurfReusKracht = uiimage(app.UIFigure);
            app.BackgroundImageSmurfReusKracht.ImageSource = fullfile(pathToMLAPP, 'Background_landscape_path.png');
            app.Maui = uiimage(app.UIFigure);
            app.Maui.ImageSource = fullfile(pathToMLAPP, 'maui_nobackground_v2.png');
            app.spaceBackground = uiimage(app.UIFigure);
            app.spaceBackground.ImageSource = fullfile(pathToMLAPP, 'Space_drawing.png');
            app.GroepEditFieldLabel = uilabel(app.UIFigure);
            app.GroepEditFieldLabel.Text = 'Groep';
            app.PlaneetX = uiimage(app.UIFigure);
            app.PlaneetX.ImageSource = fullfile(pathToMLAPP, 'PlanetX_purple.png');
            app.DENKreus = uiimage(app.UIFigure);
            app.DENKreus.Enable = 'off';
            app.DENKreus.Visible = 'off';
            app.DENKreus.ImageSource = 'balloon.png';
            app.GIFreus = uiimage(app.UIFigure);
            app.GIFreus.Enable = 'off';
            app.GIFreus.Visible = 'off';
            app.GIFreus.ImageSource = 'allgifs.gif';
            app.DENKsmurf = uiimage(app.UIFigure);
            app.DENKsmurf.Enable = 'off';
            app.DENKsmurf.Visible = 'off';
            app.DENKsmurf.ImageSource = 'balloon.png';
            app.GIFsmurf = uiimage(app.UIFigure);
            app.GIFsmurf.Enable = 'off';
            app.GIFsmurf.Visible = 'off';
            app.GIFsmurf.ImageSource = 'allgifs.gif';
            app.procentlabelEigenModel = uilabel(app.UIFigure);
            app.procentlabelEigenModel.Text = '%';
            app.SpierkrachtEigenModel = uieditfield(app.UIFigure, 'numeric');
            app.SpierkrachtEigenModel.Limits = [20 500];
            app.SpierkrachtEigenModel.ValueDisplayFormat = '%111g';
            app.SpierkrachtEigenModel.Value = 100;
            app.kgLabelEigenModel = uilabel(app.UIFigure);
            app.kgLabelEigenModel.Text = 'kg';
            app.MassaEditFieldLabel = uilabel(app.UIFigure);
            app.MassaEditFieldLabel.Text = 'Massa';
            app.ZwaartekrachtEigenModel = uieditfield(app.UIFigure, 'numeric');
            app.ZwaartekrachtEigenModel.Limits = [0.1 5];
            app.ZwaartekrachtEigenModel.ValueDisplayFormat = '%111g';
            app.ZwaartekrachtEigenModel.Value = 1;
            app.gLabelEigenModel = uilabel(app.UIFigure);
            app.gLabelEigenModel.Text = 'g';
            app.LengteLabelEigenModel = uilabel(app.UIFigure);
            app.LengteLabelEigenModel.Text = 'Lengte';
            app.cmLabelEigenModel = uilabel(app.UIFigure);
            app.cmLabelEigenModel.Text = 'cm';
            app.SpierkrachtLabelEigenModel = uilabel(app.UIFigure);
            app.SpierkrachtLabelEigenModel.Text = 'Spierkracht';
            app.ZwaartekrachtLabelEigenModel = uilabel(app.UIFigure);
            app.ZwaartekrachtLabelEigenModel.Text = 'Zwaartekracht';
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'balloon_reflected.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Enable = 'off';
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = 'allgifs.gif';
            app.CheckBoxPluto = uicheckbox(app.UIFigure);
            app.CheckBoxPluto.Text = '';
            app.maan = uiimage(app.UIFigure);
            app.maan.ImageSource = fullfile(pathToMLAPP, 'moon_drawing.png');
            app.PlanetXblack = uiimage(app.UIFigure);
            app.PlanetXblack.ImageSource = fullfile(pathToMLAPP, 'PlanetX_dark.png');
            app.Spaceman = uiimage(app.UIFigure);
            app.Spaceman.ImageSource = fullfile(pathToMLAPP, 'Spaceman_nobackground.png');
            app.Jupiter = uiimage(app.UIFigure);
            app.Jupiter.ImageSource = fullfile(pathToMLAPP, 'Jupiter_noBackground.png');
            app.Mars = uiimage(app.UIFigure);
            app.Mars.ImageSource = fullfile(pathToMLAPP, 'Mars.png');
            app.earth = uiimage(app.UIFigure);
            app.earth.ImageSource = fullfile(pathToMLAPP, 'Earth_nobackground.png');
            app.CheckBoxMaan = uicheckbox(app.UIFigure);
            app.CheckBoxMaan.Text = '';
            app.CheckBoxMars = uicheckbox(app.UIFigure);
            app.CheckBoxMars.Text = '';
            app.CheckBoxEarth = uicheckbox(app.UIFigure);
            app.CheckBoxEarth.Text = '';
            app.OpstapmetsuperkrachtBlue = uilabel(app.UIFigure);
            app.OpstapmetsuperkrachtBlue.Text = 'Op stap met superkracht!';
            app.OpstapmetsuperkrachtWhite = uilabel(app.UIFigure);
            app.OpstapmetsuperkrachtWhite.Text = 'Op stap met superkracht!';
            app.StartsimulatieButton = uibutton(app.UIFigure, 'push');
            app.StartsimulatieButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulatieButtonPushed, true);
            app.StartsimulatieButton.Text = 'Start simulatie';
            app.SluitvideoButton = uibutton(app.UIFigure, 'push');
            app.SluitvideoButton.ButtonPushedFcn = createCallbackFcn(app, @SluitvideoButtonPushed, true);
            app.SluitvideoButton.Enable = 'off';
            app.SluitvideoButton.Visible = 'off';
            app.SluitvideoButton.Text = 'Sluit video';
            app.SpeelvideoButton = uibutton(app.UIFigure, 'push');
            app.SpeelvideoButton.ButtonPushedFcn = createCallbackFcn(app, @SpeelvideoButtonPushed, true);
            app.SpeelvideoButton.Text = 'Speel video';
            app.GroepEditField = uieditfield(app.UIFigure, 'text');
            app.GroepEditField.ValueChangedFcn = createCallbackFcn(app, @GroepEditFieldValueChanged, true);
            app.GroepEditField.Placeholder = '(Geef de naam van jouw groepje)';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'Olaf_nobackground.png');
            app.Button.Text = '';
            app.Olaf = uiimage(app.UIFigure);
            app.Olaf.ImageSource = fullfile(pathToMLAPP, 'Olaf_nobackground.png');
            app.Spongebob = uiimage(app.UIFigure);
            app.Spongebob.ImageSource = fullfile(pathToMLAPP, 'Spongebob.png');
            app.Jullie_strength = uiimage(app.UIFigure);
            app.Jullie_strength.ImageSource = fullfile(pathToMLAPP, 'Jullie_nobackground.png');
            app.MrIncredible = uiimage(app.UIFigure);
            app.MrIncredible.ImageSource = fullfile(pathToMLAPP, 'incredibles2-mrincredible2.png');
            app.CheckBox_Olaf = uicheckbox(app.UIFigure);
            app.CheckBox_Olaf.Text = '';
            app.CheckBox_Spongebob = uicheckbox(app.UIFigure);
            app.CheckBox_Spongebob.Text = '';
            app.CheckBox_Jullie_strength = uicheckbox(app.UIFigure);
            app.CheckBox_Jullie_strength.Text = '';
            app.CheckBox_MrImpossible = uicheckbox(app.UIFigure);
            app.CheckBox_MrImpossible.Text = '';
            app.CheckBox_Maui = uicheckbox(app.UIFigure);
            app.CheckBox_Maui.Text = '';
            app.Ballon = uiimage(app.UIFigure);
            app.Ballon.Enable = 'off';
            app.Ballon.Visible = 'off';
            app.Ballon.ImageSource = 'balloon.png';
            app.GIF = uiimage(app.UIFigure);
            app.GIF.Enable = 'off';
            app.GIF.Visible = 'off';
            app.GIF.ImageSource = 'allgifs.gif';
            app.CheckBoxJupiter = uicheckbox(app.UIFigure);
            app.CheckBoxJupiter.Text = '';
            app.CheckBox_Smurf = uicheckbox(app.UIFigure);
            app.CheckBox_Smurf.Text = '';
            app.CheckBox_Kleuter = uicheckbox(app.UIFigure);
            app.CheckBox_Kleuter.Text = '';
            app.CheckBox_Jullie_lengte = uicheckbox(app.UIFigure);
            app.CheckBox_Jullie_lengte.Text = '';
            app.CheckBox_Volwassenen = uicheckbox(app.UIFigure);
            app.CheckBox_Volwassenen.Text = '';
            app.Jullie_lengte = uiimage(app.UIFigure);
            app.Jullie_lengte.ImageSource = fullfile(pathToMLAPP, 'Jullie_nobackground.png');
            app.Kleuters = uiimage(app.UIFigure);
            app.Kleuters.ImageSource = fullfile(pathToMLAPP, 'Kleuters.png');
            app.Volwassenen = uiimage(app.UIFigure);
            app.Volwassenen.ImageSource = fullfile(pathToMLAPP, 'Volwassenen.png');
            app.smurf = uiimage(app.UIFigure);
            app.smurf.ImageSource = fullfile(pathToMLAPP, 'Smurf_nobackground.png');
            app.giant = uiimage(app.UIFigure);
            app.giant.ImageSource = fullfile(pathToMLAPP, 'Giant_nobackground.png');
            app.CheckBox_Reus = uicheckbox(app.UIFigure);
            app.CheckBox_Reus.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpStapMetSuperKracht2025

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end